classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        ButtonGroup           matlab.ui.container.ButtonGroup    % Error Co...
        RadioButton           matlab.ui.control.RadioButton      % CRC32
        RadioButton2          matlab.ui.control.RadioButton      % 2z5
        ButtonGroup2          matlab.ui.container.ButtonGroup    % ARQ Prot...
        RadioButton3          matlab.ui.control.RadioButton      % Stop-and...
        RadioButton4          matlab.ui.control.RadioButton      % Go-Back-N
        LabelNumericEditField matlab.ui.control.Label            % Packet Size
        NumericEditField      matlab.ui.control.NumericEditField % [-Inf Inf]
        ButtonGroup3          matlab.ui.container.ButtonGroup    % Transfer...
        RadioButton5          matlab.ui.control.RadioButton      % Binary S...
        RadioButton6          matlab.ui.control.RadioButton      % Option 2
        Button                matlab.ui.control.Button           % SIMULATE
    end


    properties (Access = private)
        SIMULATION = Simulation % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % ButtonGroup selection change function
        function changeErrCtrlVer(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if(selectedButton == app.RadioButton)
                app.SIMULATION.setErrorControlVer('CRC32');
            elseif(selectedButton == app.RadioButton2)
                app.SIMULATION.setErrorControlVer('2z5');
            end       
        end

        % ButtonGroup2 selection change function
        function changeProtocolVer(app, event)
            selectedButton = app.ButtonGroup2.SelectedObject;
            if(selectedButton == app.RadioButton3)
                app.SIMULATION.setErrorControlVer('SAN');
            elseif(selectedButton == app.RadioButton4)
                app.SIMULATION.setErrorControlVer('GBN');
            end   
        end

        % NumericEditField value changed function
        function changePacketSize(app)
            value = app.NumericEditField.Value;
            app.SIMULATION.setPacketSize(value);
        end

        % Button button pushed function
        function runSimulation(app)
            app.SIMULATION.simulate;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @changeErrCtrlVer, true);
            app.ButtonGroup.Title = 'Error Control';
            app.ButtonGroup.Units = 'pixels';
            app.RadioButton = uiradiobutton(app.ButtonGroup);
            app.RadioButton.Text = 'CRC32';
            app.RadioButton.Value = true;
            app.RadioButton2 = uiradiobutton(app.ButtonGroup);
            app.RadioButton2.Text = '2z5';
            app.ButtonGroup2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @changeProtocolVer, true);
            app.ButtonGroup2.Title = 'ARQ Protocol';
            app.ButtonGroup2.Units = 'pixels';
            app.RadioButton3 = uiradiobutton(app.ButtonGroup2);
            app.RadioButton3.Text = 'Stop-and-Wait';
            app.RadioButton3.Value = true;
            app.RadioButton4 = uiradiobutton(app.ButtonGroup2);
            app.RadioButton4.Text = 'Go-Back-N';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Packet Size';
            app.NumericEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @changePacketSize);
            app.NumericEditField.ValueDisplayFormat = '%11.9g';
            app.NumericEditField.Value = 32;
            app.ButtonGroup3 = uibuttongroup(app.UIFigure);
            app.ButtonGroup3.Title = 'Transfer Model';
            app.ButtonGroup3.Units = 'pixels';
            app.RadioButton5 = uiradiobutton(app.ButtonGroup3);
            app.RadioButton5.Text = 'Binary Symmetric Channel';
            app.RadioButton5.Value = true;
            app.RadioButton6 = uiradiobutton(app.ButtonGroup3);
            app.RadioButton6.Text = 'Option 2';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @runSimulation);
            app.Button.Text = 'SIMULATE';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end